classdef carapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Panel_4            matlab.ui.container.Panel
        pEditField         matlab.ui.control.NumericEditField
        pEditFieldLabel    matlab.ui.control.Label
        EditField_4        matlab.ui.control.NumericEditField
        Label_7            matlab.ui.control.Label
        Button_3           matlab.ui.control.Button
        Panel_3            matlab.ui.container.Panel
        xmEditField        matlab.ui.control.NumericEditField
        xmEditFieldLabel   matlab.ui.control.Label
        EditField_3        matlab.ui.control.NumericEditField
        Label_6            matlab.ui.control.Label
        lmEditField        matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        kNmEditField       matlab.ui.control.NumericEditField
        kNmEditFieldLabel  matlab.ui.control.Label
        ButtonGroup        matlab.ui.container.ButtonGroup
        Label_4            matlab.ui.control.Label
        Button_2           matlab.ui.control.ToggleButton
        Button             matlab.ui.control.ToggleButton
        Panel_2            matlab.ui.container.Panel
        ms2EditField       matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        kgEditField        matlab.ui.control.NumericEditField
        kgEditFieldLabel   matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        mLabel_3           matlab.ui.control.Label
        mLabel_2           matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        mLabel             matlab.ui.control.Label
        sEditField         matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        LEditField         matlab.ui.control.NumericEditField
        LEditFieldLabel    matlab.ui.control.Label
        aEditField         matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        HEditField         matlab.ui.control.NumericEditField
        HEditFieldLabel    matlab.ui.control.Label
        Label              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            u=1;
            if app.Button.Value==true
                u=app.EditField_3.Value;
            else 
                u=0;
            end
            h=app.HEditField.Value;
            a=app.aEditField.Value;
            L=app.LEditField.Value;
            s=app.sEditField.Value;
            m=app.kgEditField.Value;
            g=app.ms2EditField.Value;
            k=app.kNmEditField.Value;
            l=app.lmEditField.Value;
            x=app.xmEditField.Value;
            t1=k/m*(l-x)^2-2*u*g*(s-x);
            if t1<0 
                app.EditField_4.Value=0;
                 app.pEditField.Value=-999999;
                return;
            end
            t2=t1-g*h-u*g*L*cos(a/180*pi);
            if t2<0 
                app.EditField_4.Value=0;
                app.pEditField.Value=-999999;
                return;
            end
            app.EditField_4.Value=1;
            app.pEditField.Value=m*(t2)^0.5;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '小车上坡能量计算软件';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '轨道相关数据';
            app.HEditFieldLabel = uilabel(app.Panel);
            app.HEditFieldLabel.Text = '平台高度H';
            app.HEditField = uieditfield(app.Panel, 'numeric');
            app.aLabel = uilabel(app.Panel);
            app.aLabel.Text = '斜面角度a';
            app.aEditField = uieditfield(app.Panel, 'numeric');
            app.LEditFieldLabel = uilabel(app.Panel);
            app.LEditFieldLabel.Text = '斜面长度L';
            app.LEditField = uieditfield(app.Panel, 'numeric');
            app.sEditFieldLabel = uilabel(app.Panel);
            app.sEditFieldLabel.Text = '坡前平地距离s';
            app.sEditField = uieditfield(app.Panel, 'numeric');
            app.mLabel = uilabel(app.Panel);
            app.mLabel.Text = 'm';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '°';
            app.mLabel_2 = uilabel(app.Panel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.Panel);
            app.mLabel_3.Text = 'm';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '小车数据';
            app.kgEditFieldLabel = uilabel(app.Panel_2);
            app.kgEditFieldLabel.Text = '质量/kg';
            app.kgEditField = uieditfield(app.Panel_2, 'numeric');
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '重力加速度 m/s^2';
            app.ms2EditField = uieditfield(app.Panel_2, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '是';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '否';
            app.Label_4 = uilabel(app.ButtonGroup);
            app.Label_4.Text = '是否考虑摩擦力';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '其他相关数据';
            app.kNmEditFieldLabel = uilabel(app.Panel_3);
            app.kNmEditFieldLabel.Text = '弹簧弹性系数k  N/m';
            app.kNmEditField = uieditfield(app.Panel_3, 'numeric');
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '弹簧原长l  /m';
            app.lmEditField = uieditfield(app.Panel_3, 'numeric');
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '摩擦系数μ';
            app.EditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.xmEditFieldLabel = uilabel(app.Panel_3);
            app.xmEditFieldLabel.Text = '弹簧压缩后长度x /m';
            app.xmEditField = uieditfield(app.Panel_3, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'jd0ozcgukd5fulnhoku58pwbfbaph6i.png');
            app.Button_3.Text = '计算';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '计算结果';
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = '小车能否冲上斜面？ 1是 0否';
            app.EditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.pEditFieldLabel = uilabel(app.Panel_4);
            app.pEditFieldLabel.Text = '小车冲上斜面后动量p';
            app.pEditField = uieditfield(app.Panel_4, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = carapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end